classdef modalities < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        AxialPanel_2    matlab.ui.container.Panel
        UIAxes2_2       matlab.ui.control.UIAxes
        BrowseButton    matlab.ui.control.Button
        SagittalPanel   matlab.ui.container.Panel
        UIAxes2_6       matlab.ui.control.UIAxes
        CoronalPanel    matlab.ui.container.Panel
        UIAxes2_4       matlab.ui.control.UIAxes
        ObliquePanel    matlab.ui.container.Panel
        UIAxes2_5       matlab.ui.control.UIAxes
        SelectplanetoincurrchangesButtonGroup  matlab.ui.container.ButtonGroup
        AxialButton     matlab.ui.control.RadioButton
        SagittalButton  matlab.ui.control.RadioButton
        CoronalButton   matlab.ui.control.RadioButton
        ObliqueButton   matlab.ui.control.RadioButton
        TextArea        matlab.ui.control.TextArea
    end

    
    properties (Access = public)
        AxHorValue
        AxVerValue
        horizontalLine % green
        verticalLine %red
        horizontalLineCor % green
        verticalLineCor
        horizontalLineSag % green
        verticalLineSag
        value
        CorHorValue
        CorVerValue
        SagHorValue
        SagVerValue
        img3d
        sizeof3d
        Sagittal
        Coronal
        Axial
        Oblique
        h
    end


    methods (Access = private)
        
%         function updateData(app)
%         app.threshold = app.UIAxes2_2.CurrentPoint(3);
%         app.horizontalLine.Value = app.threshold;
%         drawnow;
%         end
        function updateAxHor(app)
        delete(app.horizontalLine);
        app.horizontalLine = yline(app.UIAxes2_2, app.AxHorValue,'g','LineWidth',2);
        drawnow;
        app.Coronal = app.img3d(app.AxHorValue , :, :);
        imshow(imrotate(squeeze(app.Coronal),90),[],'Parent',app.UIAxes2_4),colormap gray;
        app.horizontalLineCor = yline(app.UIAxes2_4, app.CorHorValue,'g','LineWidth',2);
        hold(app.UIAxes2_4, "off")
        app.verticalLineCor = xline(app.UIAxes2_4, app.CorVerValue,'r','LineWidth',2);
        hold(app.UIAxes2_4, "off")
        end
        
        function updateAxVer(app)
        delete(app.verticalLine);
        app.verticalLine = xline(app.UIAxes2_2, app.AxVerValue,'r','LineWidth',2);
        drawnow;
        app.Sagittal = app.img3d(:,app.AxVerValue , :);
        imshow(imrotate(squeeze(app.Sagittal),90),[],'Parent',app.UIAxes2_6),colormap gray;
        app.horizontalLineSag = yline(app.UIAxes2_6, app.SagHorValue,'g','LineWidth',2);
        hold(app.UIAxes2_6, "off")
        app.verticalLineSag = xline(app.UIAxes2_6, app.SagVerValue,'r','LineWidth',2);
        hold(app.UIAxes2_6, "off")
        end
        
        function updateCorHor(app)
        delete(app.horizontalLineCor);
        app.horizontalLineCor = yline(app.UIAxes2_4, app.CorHorValue,'g','LineWidth',2);
        drawnow;
        app.Axial=app.img3d(:, :, app.CorHorValue);
        imshow(squeeze(app.Axial),[],'Parent',app.UIAxes2_2),colormap gray;
        app.horizontalLine = yline(app.UIAxes2_2, app.AxHorValue,'g','LineWidth',2);
        hold(app.UIAxes2_2, "off")
        app.verticalLine = xline(app.UIAxes2_2, app.AxVerValue,'r','LineWidth',2);
        hold(app.UIAxes2_2, "off")
        end
        
        
        function updateCorVer(app)
        delete(app.verticalLineCor);
        app.verticalLineCor = xline(app.UIAxes2_4, app.CorVerValue,'r','LineWidth',2);
        drawnow;
        app.Sagittal = app.img3d(:,app.CorVerValue , :);
        imshow(imrotate(squeeze(app.Sagittal),90),[],'Parent',app.UIAxes2_6),colormap gray;
        app.horizontalLineSag = yline(app.UIAxes2_6, app.SagHorValue,'g','LineWidth',2);
        hold(app.UIAxes2_6, "off")
        app.verticalLineSag = xline(app.UIAxes2_6, app.SagVerValue,'r','LineWidth',2);
        hold(app.UIAxes2_6, "off")
        end
        
        
        function updateSagHor(app)
        delete(app.horizontalLineSag);
        app.horizontalLineSag = yline(app.UIAxes2_6, app.SagHorValue,'g','LineWidth',2);
        drawnow;
        app.Axial=app.img3d(:, :, app.SagHorValue);
        imshow(squeeze(app.Axial),[],'Parent',app.UIAxes2_2),colormap gray;
        app.horizontalLine = yline(app.UIAxes2_2, app.AxHorValue,'g','LineWidth',2);
        hold(app.UIAxes2_2, "off")
        app.verticalLine = xline(app.UIAxes2_2, app.AxVerValue,'r','LineWidth',2);
        hold(app.UIAxes2_2, "off")
        end
        
        
        function updateSagVer(app)
        delete(app.verticalLineSag);
        app.verticalLineSag = xline(app.UIAxes2_6, app.SagVerValue,'r','LineWidth',2);
        drawnow;
        app.Coronal = app.img3d(app.SagVerValue , :, :);
        imshow(imrotate(squeeze(app.Coronal),90),[],'Parent',app.UIAxes2_4),colormap gray;
        app.horizontalLineCor = yline(app.UIAxes2_4, app.CorHorValue,'g','LineWidth',2);
        hold(app.UIAxes2_4, "off")
        app.verticalLineCor = xline(app.UIAxes2_4, app.CorVerValue,'r','LineWidth',2);
        hold(app.UIAxes2_4, "off")
        end
        
    end
    
    
    methods (Access = public)


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            fPath = uigetdir( 'C:\');
            files_wanted = dir(fullfile(fPath, '*.dcm'));
            %if user canceled browsing 
%             if (files_wanted==0)
%                  return;
%             end
            Slices=length(files_wanted);
            for n=1:Slices
                FileName = fullfile(fPath, files_wanted(n).name);
                images{n}=dicomread(FileName); 
                img2d = images{n};
                app.img3d(:,:,n) = img2d;
            end
            app.sizeof3d=size(app.img3d);
            m=app.sizeof3d(1);
            app.Axial=app.img3d(:, :, (app.sizeof3d(3))./2);
            imshow(squeeze(app.Axial),[],'Parent',app.UIAxes2_2),colormap gray;
            point = [20 30 15.5];
            normal = [0 10 20];
            app.Oblique = obliqueslice(squeeze(app.img3d),point,normal);
            imshow(squeeze(app.Oblique),[],'Parent',app.UIAxes2_5),colormap gray;
            %enableDefaultInteractivity(app.UIAxes2_2); 
            disableDefaultInteractivity(app.UIAxes2_2)
            disableDefaultInteractivity(app.UIAxes2_4)
            disableDefaultInteractivity(app.UIAxes2_6)
            app.AxHorValue = 250;
            app.AxVerValue = 250;
            app.CorHorValue = 100;
            app.CorVerValue = 250;
            app.SagHorValue = 100;
            app.SagVerValue = 250;
            x=size(app.Axial);
            disp(x(1));
            
            %app.UIAxes2_2.ylim([0 x(2)]);
            app.horizontalLine = yline(app.UIAxes2_2, app.AxHorValue,'g','LineWidth',2);
            app.h = drawline(app.UIAxes2_2,'Position',[0 0;500 500]);
            hold(app.UIAxes2_2, "off")
            app.verticalLine = xline(app.UIAxes2_2, app.AxVerValue,'r','LineWidth',2);
            hold(app.UIAxes2_2, "off")
              
            
%             disp(sizeof3d)
%             disp(size(Axial))
%             disp((sizeof3d(3))./2)
            
            app.Sagittal = app.img3d(:,(app.sizeof3d(1))./2 , :);
            
            
            app.Coronal = app.img3d((app.sizeof3d(2))./2 , :, :);
            
            imshow(imrotate(squeeze(app.Coronal),90),[],'Parent',app.UIAxes2_4),colormap gray;
            
            app.horizontalLineCor = yline(app.UIAxes2_4, app.CorHorValue,'g','LineWidth',2);
            hold(app.UIAxes2_4, "off")
            app.verticalLineCor = xline(app.UIAxes2_4, app.CorVerValue,'r','LineWidth',2);
            hold(app.UIAxes2_4, "off")
            imshow(imrotate(squeeze(app.Sagittal),90),[],'Parent',app.UIAxes2_6),colormap gray;
            app.horizontalLineSag = yline(app.UIAxes2_6, app.SagHorValue,'g','LineWidth',2);
            hold(app.UIAxes2_6, "off")
            app.verticalLineSag = xline(app.UIAxes2_6, app.SagVerValue,'r','LineWidth',2);
            hold(app.UIAxes2_6, "off")
            %linkaxes([app.UIAxes2_2 app.UIAxes2_3 app.UIAxes2_4]);
        end

        % Window key press function: UIFigure
        function UIFigureWindowKeyPress(app, event)
        
        if app.AxialButton.Value
            if strcmp(event.Key,'rightarrow')==1
                app.AxVerValue=app.AxVerValue+1;
                app.updateAxVer;
            elseif strcmp(event.Key, 'leftarrow')==1
                app.AxVerValue=app.AxVerValue-1;
                app.updateAxVer;
                
            elseif strcmp(event.Key, 'downarrow')==1
                app.AxHorValue=app.AxHorValue+1;
                app.updateAxHor;
                
            elseif strcmp(event.Key, 'uparrow')==1
                app.AxHorValue=app.AxHorValue-1;
                app.updateAxHor;
            end
        end
        if app.CoronalButton.Value
            if strcmp(event.Key,'rightarrow')==1
                app.CorVerValue=app.CorVerValue+1;
                app.updateCorVer;
            elseif strcmp(event.Key, 'leftarrow')==1
                app.CorVerValue=app.CorVerValue-1;
                app.updateCorVer;
                
            elseif strcmp(event.Key, 'downarrow')==1
                app.CorHorValue=app.CorHorValue+1;
                app.updateCorHor;
                
            elseif strcmp(event.Key, 'uparrow')==1
                app.CorHorValue=app.CorHorValue-1;
                app.updateCorHor;
            end
        end
        if app.SagittalButton.Value
            if strcmp(event.Key,'rightarrow')==1
                app.SagVerValue=app.SagVerValue+1;
                app.updateSagVer;
            elseif strcmp(event.Key, 'leftarrow')==1
                app.SagVerValue=app.SagVerValue-1;
                app.updateSagVer;
                
            elseif strcmp(event.Key, 'downarrow')==1
                app.SagHorValue=app.SagHorValue+1;
                app.updateSagHor;
                
            elseif strcmp(event.Key, 'uparrow')==1
                app.SagHorValue=app.SagHorValue-1;
                app.updateSagHor;
            end
        end
        
       
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowKeyPressFcn = createCallbackFcn(app, @UIFigureWindowKeyPress, true);
            app.AxialPanel_2 = uipanel(app.UIFigure);
            app.AxialPanel_2.Title = 'Axial';
            app.UIAxes2_2 = uiaxes(app.AxialPanel_2);
            title(app.UIAxes2_2, 'Title')
            xlabel(app.UIAxes2_2, 'X')
            ylabel(app.UIAxes2_2, 'Y')
            zlabel(app.UIAxes2_2, 'Z')
            app.BrowseButton = uibutton(app.UIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.SagittalPanel = uipanel(app.UIFigure);
            app.SagittalPanel.Title = 'Sagittal';
            app.UIAxes2_6 = uiaxes(app.SagittalPanel);
            title(app.UIAxes2_6, 'Title')
            xlabel(app.UIAxes2_6, 'X')
            ylabel(app.UIAxes2_6, 'Y')
            zlabel(app.UIAxes2_6, 'Z')
            app.CoronalPanel = uipanel(app.UIFigure);
            app.CoronalPanel.Title = 'Coronal';
            app.UIAxes2_4 = uiaxes(app.CoronalPanel);
            title(app.UIAxes2_4, 'Title')
            xlabel(app.UIAxes2_4, 'X')
            ylabel(app.UIAxes2_4, 'Y')
            zlabel(app.UIAxes2_4, 'Z')
            app.ObliquePanel = uipanel(app.UIFigure);
            app.ObliquePanel.Title = 'Oblique';
            app.UIAxes2_5 = uiaxes(app.ObliquePanel);
            title(app.UIAxes2_5, 'Title')
            xlabel(app.UIAxes2_5, 'X')
            ylabel(app.UIAxes2_5, 'Y')
            zlabel(app.UIAxes2_5, 'Z')
            app.SelectplanetoincurrchangesButtonGroup = uibuttongroup(app.UIFigure);
            app.SelectplanetoincurrchangesButtonGroup.Title = 'Select plane to incurr changes';
            app.AxialButton = uiradiobutton(app.SelectplanetoincurrchangesButtonGroup);
            app.AxialButton.Text = 'Axial';
            app.AxialButton.Value = true;
            app.SagittalButton = uiradiobutton(app.SelectplanetoincurrchangesButtonGroup);
            app.SagittalButton.Text = 'Sagittal';
            app.CoronalButton = uiradiobutton(app.SelectplanetoincurrchangesButtonGroup);
            app.CoronalButton.Text = 'Coronal';
            app.ObliqueButton = uiradiobutton(app.SelectplanetoincurrchangesButtonGroup);
            app.ObliqueButton.Text = 'Oblique';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'Instructions:'; 'Left & right arrow to move red vertical line'; ''; ''; 'Up & down arrow to move green horizontal line'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = modalities

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end