classdef modalitiesFinaal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        AxialPanel_2                matlab.ui.container.Panel
        UIAxes2_2                   matlab.ui.control.UIAxes
        BrowseButton                matlab.ui.control.Button
        SagittalPanel               matlab.ui.container.Panel
        UIAxes2_6                   matlab.ui.control.UIAxes
        CoronalPanel                matlab.ui.container.Panel
        UIAxes2_4                   matlab.ui.control.UIAxes
        ObliquePanel                matlab.ui.container.Panel
        UIAxes2_5                   matlab.ui.control.UIAxes
        SelectplanetoincurrchangesButtonGroup  matlab.ui.container.ButtonGroup
        AxialButton                 matlab.ui.control.RadioButton
        SagittalButton              matlab.ui.control.RadioButton
        CoronalButton               matlab.ui.control.RadioButton
        ObliqueButton               matlab.ui.control.RadioButton
        HorizontalEditFieldLabel    matlab.ui.control.Label
        HorizontalEditField         matlab.ui.control.NumericEditField
        VerticalEditFieldLabel      matlab.ui.control.Label
        VerticalEditField           matlab.ui.control.NumericEditField
        TextArea                    matlab.ui.control.TextArea
        SelectonetodrawButtonGroup  matlab.ui.container.ButtonGroup
        LineButton                  matlab.ui.control.RadioButton
        PolygonButton               matlab.ui.control.RadioButton
        TwolinesButton              matlab.ui.control.RadioButton
        EllipseButton               matlab.ui.control.RadioButton
        LineButton_2                matlab.ui.control.RadioButton
        DeleteButton_2              matlab.ui.control.RadioButton
        EditField                   matlab.ui.control.NumericEditField
        Label                       matlab.ui.control.Label
    end

    
    properties (Access = public)
        AxHorValue
        AxVerValue
        horizontalLine % green
        verticalLine %red
        horizontalLineCor % green
        verticalLineCor
        horizontalLineSag % green
        verticalLineSag
        obliqueLine
        value
        CorHorValue
        CorVerValue
        SagHorValue
        SagVerValue
        img3d
        sizeof3d
        Sagittal
        Coronal
        Axial
        Oblique
        point
        normal
        pointx1
        pointy1
        pointx2
        pointy2
        pointz
        flag=false
        val
        line
        line2
        dist
        area
        ellipse
        elliarea
        polygon
    end


    methods (Access = private)
        
        % Horizontal axis update movement for axial plane
        function updateAxHor(app)
        AxialSize=size(app.Axial);
        delete(app.horizontalLine);
        app.horizontalLine = yline(app.UIAxes2_2, app.AxHorValue,'g','LineWidth',2);
        drawnow;
        
        if((app.AxHorValue<0) | (app.AxHorValue>AxialSize(1)) )
            
           return;
        else
            app.Coronal = app.img3d(app.AxHorValue , :, :);
            imshow(imrotate(squeeze(app.Coronal),90),[],'Parent',app.UIAxes2_4),colormap gray;
        end
        app.horizontalLineCor = yline(app.UIAxes2_4, app.CorHorValue,'g','LineWidth',2);
        hold(app.UIAxes2_4, "off")
        app.verticalLineCor = xline(app.UIAxes2_4, app.CorVerValue,'r','LineWidth',2);
        hold(app.UIAxes2_4, "off")

        end
        
        % Vertical axis update movement for axial plane
        function updateAxVer(app)
        delete(app.verticalLine);
        app.verticalLine = xline(app.UIAxes2_2, app.AxVerValue,'r','LineWidth',2);
        drawnow;
        app.Sagittal = app.img3d(:,app.AxVerValue , :);
        imshow(imrotate(squeeze(app.Sagittal),90),[],'Parent',app.UIAxes2_6),colormap gray;
        disp('vertical line axial')
        disp(app.AxVerValue)
        disp('end vertical line axial')
        app.horizontalLineSag = yline(app.UIAxes2_6, app.SagHorValue,'g','LineWidth',2);
        hold(app.UIAxes2_6, "off")
        app.verticalLineSag = xline(app.UIAxes2_6, app.SagVerValue,'r','LineWidth',2);
        hold(app.UIAxes2_6, "off")
        end
        % Horizontal axis update movement for coronal plane
        function updateCorHor(app)
        delete(app.horizontalLineCor);
        app.horizontalLineCor = yline(app.UIAxes2_4, app.CorHorValue,'g','LineWidth',2);
        drawnow;
        app.Axial=app.img3d(:, :,((app.sizeof3d(3))- app.CorHorValue-1));
        imshow(squeeze(app.Axial),[],'Parent',app.UIAxes2_2),colormap gray;
        app.horizontalLine = yline(app.UIAxes2_2, app.AxHorValue,'g','LineWidth',2);
        hold(app.UIAxes2_2, "off")
        app.verticalLine = xline(app.UIAxes2_2, app.AxVerValue,'r','LineWidth',2);
        hold(app.UIAxes2_2, "off")
        app.obliqueLine = drawline(app.UIAxes2_2,'Position',[0 0;500 500]);

        end
        
        % Vertical axis update movement for coronal plane
        function updateCorVer(app)
        delete(app.verticalLineCor);
        app.verticalLineCor = xline(app.UIAxes2_4, app.CorVerValue,'r','LineWidth',2);
        drawnow;
        app.Sagittal = app.img3d(:,app.CorVerValue , :);
        imshow(imrotate(squeeze(app.Sagittal),90),[],'Parent',app.UIAxes2_6),colormap gray;
        app.horizontalLineSag = yline(app.UIAxes2_6, app.SagHorValue,'g','LineWidth',2);
        hold(app.UIAxes2_6, "off")
        app.verticalLineSag = xline(app.UIAxes2_6, app.SagVerValue,'r','LineWidth',2);
        hold(app.UIAxes2_6, "off")
        
        end
        
        % Horizontal axis update movement for Saggital plane
        function updateSagHor(app)
        delete(app.horizontalLineSag);
        app.horizontalLineSag = yline(app.UIAxes2_6, app.SagHorValue,'g','LineWidth',2);
        drawnow;
        app.Axial=app.img3d(:, :, ((app.sizeof3d(3))-app.SagHorValue-1));
        imshow(squeeze(app.Axial),[],'Parent',app.UIAxes2_2),colormap gray;
        app.horizontalLine = yline(app.UIAxes2_2, app.AxHorValue,'g','LineWidth',2);
        hold(app.UIAxes2_2, "off")
        app.verticalLine = xline(app.UIAxes2_2, app.AxVerValue,'r','LineWidth',2);
        hold(app.UIAxes2_2, "off")
        app.obliqueLine = drawline(app.UIAxes2_2,'Position',[0 0;500 500]);
        end
        
        % Vertical axis update movement for Saggital plane
        function updateSagVer(app)
        delete(app.verticalLineSag);
        app.verticalLineSag = xline(app.UIAxes2_6, app.SagVerValue,'r','LineWidth',2);
        drawnow;
        app.Coronal = app.img3d(app.SagVerValue , :, :);
        imshow(imrotate(squeeze(app.Coronal),90),[],'Parent',app.UIAxes2_4),colormap gray;
        app.horizontalLineCor = yline(app.UIAxes2_4, app.CorHorValue,'g','LineWidth',2);
        hold(app.UIAxes2_4, "off")
        app.verticalLineCor = xline(app.UIAxes2_4, app.CorVerValue,'r','LineWidth',2);
        hold(app.UIAxes2_4, "off")
        end
        
        % Oblique slices & movement update
        function allevents(app,src,evt)
        evname = evt.EventName;
            switch(evname)
                case{'ROIMoved'}
                    disp(['ROI moved current position: ' mat2str(evt.CurrentPosition())])
                    app.pointx1=evt.CurrentPosition(1,1);
                    app.pointy1=evt.CurrentPosition(1,2);
                    app.pointx2=evt.CurrentPosition(2,1);
                    app.pointy2=evt.CurrentPosition(2,2);
                    midpointX=round((app.pointx1+app.pointx2)/2);
                    midpointY=round((app.pointy1+app.pointy2)/2);
                    rise=app.pointy2-app.pointy1;
                    run=app.pointx2-app.pointx1;
                    angle = atand(rise/run);
                    disp(angle)
                    newrise=-run;
                    newrun=rise;
                    app.point=[midpointX midpointY 100];
                    app.normal = [newrun newrise 0];
                    app.Oblique = obliqueslice(app.img3d,app.point,app.normal,'OutputSize','Full');
                    imshow(imrotate(app.Oblique,(angle-180)),[],'Parent',app.UIAxes2_5),colormap gray;
                    
            end
        end

        % Calculate the length of a line
        function Drawline(app, src,evt)
            hold(app.UIAxes2_5, 'on') 
            
            app.line = drawline(app.UIAxes2_5);
                
                disp('Trying')
                disp(app.line.Position);
                disp(app.line.Position(1));
                disp(app.line.Position(2));
                disp(app.line.Position(3));
                disp(app.line.Position(4));
                p1 = [app.line.Position(1) app.line.Position(3)];
                p2 = [app.line.Position(2) app.line.Position(4)];

                app.dist = sqrt( (p2-p1) * (p2-p1).' );
                
               
                disp('distancevalue')
                disp(app.dist);
                set(app.EditField,'Visible','on');
                set(app.EditField,'Value',app.dist);
                set(app.Label,'Visible','on');
                set(app.Label,'Text','Distance');
            
                
        end

        % Calculate area of a polygon
        function Drawpolygon(app, event)
                hold(app.UIAxes2_5, 'on') 
                app.polygon = drawpolygon(app.UIAxes2_5);
                disp(app.polygon.Position);
                x=app.polygon.Position(:,1);
                y=app.polygon.Position(:,2);
                app.area = polyarea(x, y);

                set(app.EditField,'Visible','on');
                set(app.EditField,'Value',app.area);
                set(app.Label,'Visible','on');
                set(app.Label,'Text','Area of Polygon');
                
        end

        % Calculating angle 
        function DrawTwoLines(app, event)
                hold(app.UIAxes2_5, 'on') 
                app.line = drawline(app.UIAxes2_5);
                
                app.line2 = drawline(app.UIAxes2_5);
                disp(app.line.Position);
                disp(app.line.Position(1));
                disp(app.line.Position(2));
                disp(app.line.Position(3));
                disp(app.line.Position(4));
                p1 = [app.line.Position(1) app.line.Position(3)];
                p2 = [app.line.Position(2) app.line.Position(4)];

                
                p4 = [app.line2.Position(2) app.line2.Position(4)];
                
                
                v_1 = [p2(1),p2(2),0] - [p1(1),p1(2),0];
                v_2 = [p4(1),p4(2),0] - [p1(1),p1(2),0];
                Theta = atan2(norm(cross(v_1, v_2)), dot(v_1, v_2));
                disp(Theta);

                set(app.EditField,'Visible','on');
                set(app.EditField,'Value',Theta);
                set(app.Label,'Visible','on');
                set(app.Label,'Text','Theta');

                
        end
        % Calculate area of elipse       
        function DrawEllipse(app, event)
                hold(app.UIAxes2_5, 'on') 
                app.ellipse = drawellipse(app.UIAxes2_5);
                disp(app.ellipse.SemiAxes);
                disp(app.ellipse.SemiAxes(1)/2);
                disp(app.ellipse.SemiAxes(2)/2);
                app.elliarea = 3.142 * app.ellipse.SemiAxes(1) * app.ellipse.SemiAxes(2);
                disp(round(app.elliarea));
                
                set(app.EditField,'Visible','on');
                set(app.EditField,'Value',app.elliarea);
                set(app.Label,'Visible','on');
                set(app.Label,'Text','Area of Ellipse');
        end

        % To accomdate the change/update in the drawing
        function  updateDrawLine(app,src,evt)
            evname = evt.EventName;
            hold(app.UIAxes2_5, 'on') 
            switch(evname)
                case{'ROIMoved'}
                
                disp('Trying')
                p1 = [app.line.Position(1) app.line.Position(3)];
                p2 = [app.line.Position(2) app.line.Position(4)];

                app.dist = sqrt( (p2-p1) * (p2-p1).' );
                
               
                disp('distancevalue')
                disp(app.dist);
                set(app.EditField,'Visible','on');
                set(app.EditField,'Value',app.dist);
                set(app.Label,'Visible','on');
                set(app.Label,'Text','Distance');
            end
                
        end
        
        % To accomdate the change/update in the drawing
        function  updateDrawTwoLines(app,~,evt)
            evname = evt.EventName;
            hold(app.UIAxes2_5, 'on') 
            switch(evname)
                case{'ROIMoved'}
                
                disp('Trying two lines')
                p1 = [app.line.Position(1) app.line.Position(3)];
                p2 = [app.line.Position(2) app.line.Position(4)];
                p4 = [app.line2.Position(2) app.line2.Position(4)];
                
                
                v_1 = [p2(1),p2(2),0] - [p1(1),p1(2),0];
                v_2 = [p4(1),p4(2),0] - [p1(1),p1(2),0];
                Theta = atan2(norm(cross(v_1, v_2)), dot(v_1, v_2));
                disp(Theta);

                set(app.EditField,'Visible','on');
                set(app.EditField,'Value',Theta);
                set(app.Label,'Visible','on');
                set(app.Label,'Text','Theta');

            end
        end
        
        % To accomdate the change/update in the drawing
        function updateDrawEllipse(app,src,evt)
            evname = evt.EventName;
            hold(app.UIAxes2_5, 'on') 
            switch(evname)
                case{'ROIMoved'}
            
                disp(app.ellipse.SemiAxes);
                disp(app.ellipse.SemiAxes(1)/2);
                disp(app.ellipse.SemiAxes(2)/2);
                app.elliarea = 3.142 * app.ellipse.SemiAxes(1)/2 * app.ellipse.SemiAxes(2)/2;
                disp(round(app.elliarea));
      
                set(app.EditField,'Visible','on');
                set(app.EditField,'Value',app.elliarea);
                set(app.Label,'Visible','on');
                set(app.Label,'Text','Area of Ellipse');

            end
        end
        
        % To accomdate the change/update in the drawing
        function updateDrawPolygon(app,src,evt)
            evname = evt.EventName;
            hold(app.UIAxes2_5, 'on') 
            switch(evname)
                case{'ROIMoved'}
            
                x=app.polygon.Position(:,1);
                y=app.polygon.Position(:,2);
                app.area = polyarea(x, y);

                set(app.EditField,'Visible','on');
                set(app.EditField,'Value',app.area);
                set(app.Label,'Visible','on');
                set(app.Label,'Text','Area of Polygon');

            end
        end
    end
    
    
    methods (Access = public)


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            try
            fPath = uigetdir( 'C:\');
            files_wanted = dir(fullfile(fPath, '*.dcm'));
            

            app.flag=true;
            Slices=length(files_wanted);
            for n=1:Slices
                FileName = fullfile(fPath, files_wanted(n).name);
                images{n}=dicomread(FileName); 
                img2d = images{n};
                app.img3d(:,:,n) = img2d;
            end
         
            app.sizeof3d=size(app.img3d);
            m=app.sizeof3d(1);
            app.Axial=app.img3d(:, :, (app.sizeof3d(3))./2);
            imshow(squeeze(app.Axial),[],'Parent',app.UIAxes2_2),colormap gray;

            disableDefaultInteractivity(app.UIAxes2_2)
            disableDefaultInteractivity(app.UIAxes2_4)
            disableDefaultInteractivity(app.UIAxes2_6)
            disableDefaultInteractivity(app.UIAxes2_5)
            app.AxHorValue = 250;
            app.AxVerValue = 250;
            app.CorHorValue = 100;
            app.CorVerValue = 250;
            app.SagHorValue = 100;
            app.SagVerValue = 250;
            
            AxialSize=size(app.Axial);
            
            app.horizontalLine = yline(app.UIAxes2_2,app.AxHorValue,'g','LineWidth',2);
            
            app.obliqueLine = drawline(app.UIAxes2_2,'Position',[0 0;500 500]);

            app.point = [40 60 13];
            app.normal = [5 5 -20];
            app.Oblique = obliqueslice(app.img3d,app.point,app.normal);
           
            imshow(imrotate(app.Oblique,180),[],'Parent',app.UIAxes2_5),colormap gray;
              addlistener(app.obliqueLine,'ROIMoved',@app.allevents);


            hold(app.UIAxes2_2, "off")
            app.verticalLine = xline(app.UIAxes2_2, app.AxVerValue,'r','LineWidth',2);
            hold(app.UIAxes2_2, "off")
              
            
            app.Sagittal = app.img3d(:,(app.sizeof3d(1))./2 , :);
            
            
            app.Coronal = app.img3d((app.sizeof3d(2))./2 , :, :);
            
            imshow(imrotate(squeeze(app.Coronal),90),[],'Parent',app.UIAxes2_4),colormap gray;
            
            app.horizontalLineCor = yline(app.UIAxes2_4, app.CorHorValue,'g','LineWidth',2);
            hold(app.UIAxes2_4, "off")
            app.verticalLineCor = xline(app.UIAxes2_4, app.CorVerValue,'r','LineWidth',2);
            hold(app.UIAxes2_4, "off")
            imshow(imrotate(squeeze(app.Sagittal),90),[],'Parent',app.UIAxes2_6),colormap gray;
            app.horizontalLineSag = yline(app.UIAxes2_6, app.SagHorValue,'g','LineWidth',2);
            hold(app.UIAxes2_6, "off")
            app.verticalLineSag = xline(app.UIAxes2_6, app.SagVerValue,'r','LineWidth',2);
            hold(app.UIAxes2_6, "off")
            disp(size(app.Axial));%x & y 512 512
            disp(size(app.Coronal));%y % z 512 234
            disp(size(app.Sagittal));% x & z 512 234
            catch ME
               warndlg('Please choose a dcm directory','Warning');
            end
            
        end

        % Window key press function: UIFigure
        function UIFigureWindowKeyPress(app, event)
        
        if app.AxialButton.Value
           
            if strcmp(event.Key,'rightarrow')==1
                app.AxVerValue=app.AxVerValue+1;
                app.updateAxVer;
                set(app.VerticalEditField,'Value',app.AxVerValue)

            elseif strcmp(event.Key, 'leftarrow')==1
                app.AxVerValue=app.AxVerValue-1;
                app.updateAxVer;
                set(app.VerticalEditField,'Value',app.AxVerValue)
                
            elseif strcmp(event.Key, 'downarrow')==1
                app.AxHorValue=app.AxHorValue+1;
                app.updateAxHor;
                set(app.HorizontalEditField,'Value',app.AxHorValue)
                
            elseif strcmp(event.Key, 'uparrow')==1
                app.AxHorValue=app.AxHorValue-1;
                app.updateAxHor;
                set(app.HorizontalEditField,'Value',app.AxHorValue)

          
            end
        end
        if app.CoronalButton.Value
            if strcmp(event.Key,'rightarrow')==1
                app.CorVerValue=app.CorVerValue+1;
                app.updateCorVer;
                set(app.VerticalEditField,'Value',app.CorVerValue)
                
            elseif strcmp(event.Key, 'leftarrow')==1
                app.CorVerValue=app.CorVerValue-1;
                app.updateCorVer;
                set(app.VerticalEditField,'Value',app.CorVerValue)
                
            elseif strcmp(event.Key, 'downarrow')==1
                app.CorHorValue=app.CorHorValue+1;
                app.updateCorHor;
                set(app.HorizontalEditField,'Value',app.CorHorValue)

                
            elseif strcmp(event.Key, 'uparrow')==1
                app.CorHorValue=app.CorHorValue-1;
                app.updateCorHor;
                set(app.HorizontalEditField,'Value',app.CorHorValue)

            end
        end
        if app.SagittalButton.Value
            if strcmp(event.Key,'rightarrow')==1
                app.SagVerValue=app.SagVerValue+1;
                app.updateSagVer;
                set(app.VerticalEditField,'Value',app.SagVerValue)
                
            elseif strcmp(event.Key, 'leftarrow')==1
                app.SagVerValue=app.SagVerValue-1;
                app.updateSagVer;
                set(app.VerticalEditField,'Value',app.SagVerValue)
                
            elseif strcmp(event.Key, 'downarrow')==1
                app.SagHorValue=app.SagHorValue+1;
                app.updateSagHor;
                set(app.HorizontalEditField,'Value',app.SagHorValue)

                
            elseif strcmp(event.Key, 'uparrow')==1
                app.SagHorValue=app.SagHorValue-1;
                app.updateSagHor;
                set(app.HorizontalEditField,'Value',app.SagHorValue)

            end
            
        end

       
        end

        % Value changed function: HorizontalEditField
        function HorizontalEditFieldValueChanged(app, event)
            selectedButton = app.SelectplanetoincurrchangesButtonGroup.SelectedObject;
            if selectedButton.Text =="Axial"
               app.AxHorValue=app.HorizontalEditField.Value;
            elseif selectedButton.Text =="Coronal"
                app.CorHorValue=app.HorizontalEditField.Value;
            elseif selectedButton.Text =="Sagittal"
                app.SagHorValue=app.HorizontalEditField.Value;
            end

            
        end

        % Selection changed function: SelectonetodrawButtonGroup
        function SelectonetodrawButtonGroupSelectionChanged(app, event)
      
            selectedButton = app.SelectonetodrawButtonGroup.SelectedObject;
            disp(selectedButton);
           
            if selectedButton.Text =="Line"
                app.Drawline(app);
                addlistener(app.line,'ROIMoved',@app.updateDrawLine);
          
            elseif selectedButton.Text == "Polygon"
                app.Drawpolygon(app);
                addlistener(app.polygon,'ROIMoved',@app.updateDrawPolygon);

                
            elseif selectedButton.Text == "Two lines"
                app.DrawTwoLines(app);
                addlistener(app.line,'ROIMoved',@app.updateDrawTwoLines);
                addlistener(app.line2,'ROIMoved',@app.updateDrawTwoLines);

            elseif selectedButton.Text == "Ellipse"
                app.DrawEllipse(app);
                addlistener(app.ellipse,'ROIMoved',@app.updateDrawEllipse);
                
            elseif selectedButton.Text == "Delete"
                delete(app.line);
                delete(app.line2);
                delete(app.ellipse);
                delete(app.polygon);
            end
            
        end

        % Callback function
        function DeleteButtonPushed(app, event)

            delete(app.line);
            delete(app.line2);
            delete(app.ellipse);
            delete(app.polygon);
        end

        % Callback function
        function areaofpolygonButtonPushed(app, event)
            app.Area(app);
        end

        % Selection changed function: 
        % SelectplanetoincurrchangesButtonGroup
        function SelectplanetoincurrchangesButtonGroupSelectionChanged(app, event)
            selectedButton = app.SelectplanetoincurrchangesButtonGroup.SelectedObject;
            if selectedButton.Text == "Oblique"
                  if(app.flag==true)
                  addlistener(app.obliqueLine,'ROIMoved',@app.allevents);
                  end
            end
        end

        % Value changed function: VerticalEditField
        function VerticalEditFieldValueChanged(app, event)
            selectedButton = app.SelectplanetoincurrchangesButtonGroup.SelectedObject;

            if selectedButton.Text =="Axial"
               app.AxVerValue=app.VerticalEditField.Value;
            elseif selectedButton.Text =="Coronal"
                app.CorVerValue=app.VerticalEditField.Value;
            elseif selectedButton.Text =="Sagittal"
                app.SagVerValue=app.VerticalEditField.Value;
            end            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowKeyPressFcn = createCallbackFcn(app, @UIFigureWindowKeyPress, true);
            app.AxialPanel_2 = uipanel(app.UIFigure);
            app.AxialPanel_2.Title = 'Axial';
            app.UIAxes2_2 = uiaxes(app.AxialPanel_2);
            title(app.UIAxes2_2, 'Title')
            xlabel(app.UIAxes2_2, 'X')
            ylabel(app.UIAxes2_2, 'Y')
            zlabel(app.UIAxes2_2, 'Z')
            app.UIAxes2_2.XLim = [0 512];
            app.UIAxes2_2.YLim = [0 512];
            app.BrowseButton = uibutton(app.UIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.SagittalPanel = uipanel(app.UIFigure);
            app.SagittalPanel.Title = 'Sagittal';
            app.UIAxes2_6 = uiaxes(app.SagittalPanel);
            title(app.UIAxes2_6, 'Title')
            xlabel(app.UIAxes2_6, 'X')
            ylabel(app.UIAxes2_6, 'Y')
            zlabel(app.UIAxes2_6, 'Z')
            app.UIAxes2_6.XLim = [0 512];
            app.UIAxes2_6.ZLim = [0 512];
            app.CoronalPanel = uipanel(app.UIFigure);
            app.CoronalPanel.Title = 'Coronal';
            app.UIAxes2_4 = uiaxes(app.CoronalPanel);
            title(app.UIAxes2_4, 'Title')
            xlabel(app.UIAxes2_4, 'X')
            ylabel(app.UIAxes2_4, 'Y')
            zlabel(app.UIAxes2_4, 'Z')
            app.UIAxes2_4.XLim = [0 512];
            app.UIAxes2_4.ZLim = [0 234];
            app.ObliquePanel = uipanel(app.UIFigure);
            app.ObliquePanel.Title = 'Oblique';
            app.UIAxes2_5 = uiaxes(app.ObliquePanel);
            title(app.UIAxes2_5, 'Title')
            xlabel(app.UIAxes2_5, 'X')
            ylabel(app.UIAxes2_5, 'Y')
            zlabel(app.UIAxes2_5, 'Z')
            app.SelectplanetoincurrchangesButtonGroup = uibuttongroup(app.UIFigure);
            app.SelectplanetoincurrchangesButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SelectplanetoincurrchangesButtonGroupSelectionChanged, true);
            app.SelectplanetoincurrchangesButtonGroup.Title = 'Select plane to incurr changes';
            app.AxialButton = uiradiobutton(app.SelectplanetoincurrchangesButtonGroup);
            app.AxialButton.Text = 'Axial';
            app.AxialButton.Value = true;
            app.SagittalButton = uiradiobutton(app.SelectplanetoincurrchangesButtonGroup);
            app.SagittalButton.Text = 'Sagittal';
            app.CoronalButton = uiradiobutton(app.SelectplanetoincurrchangesButtonGroup);
            app.CoronalButton.Text = 'Coronal';
            app.ObliqueButton = uiradiobutton(app.SelectplanetoincurrchangesButtonGroup);
            app.ObliqueButton.Text = 'Oblique';
            app.HorizontalEditFieldLabel = uilabel(app.SelectplanetoincurrchangesButtonGroup);
            app.HorizontalEditFieldLabel.Text = 'Horizontal';
            app.HorizontalEditField = uieditfield(app.SelectplanetoincurrchangesButtonGroup, 'numeric');
            app.HorizontalEditField.ValueChangedFcn = createCallbackFcn(app, @HorizontalEditFieldValueChanged, true);
            app.VerticalEditFieldLabel = uilabel(app.SelectplanetoincurrchangesButtonGroup);
            app.VerticalEditFieldLabel.Text = 'Vertical';
            app.VerticalEditField = uieditfield(app.SelectplanetoincurrchangesButtonGroup, 'numeric');
            app.VerticalEditField.ValueChangedFcn = createCallbackFcn(app, @VerticalEditFieldValueChanged, true);
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'Instructions:'; 'Use arrows or Input fields '; 'Left & right arrow to move red vertical line'; 'Up & down arrow to move green horizontal line'};
            app.SelectonetodrawButtonGroup = uibuttongroup(app.UIFigure);
            app.SelectonetodrawButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SelectonetodrawButtonGroupSelectionChanged, true);
            app.SelectonetodrawButtonGroup.Title = 'Select one to draw';
            app.LineButton = uiradiobutton(app.SelectonetodrawButtonGroup);
            app.LineButton.Text = 'Line';
            app.PolygonButton = uiradiobutton(app.SelectonetodrawButtonGroup);
            app.PolygonButton.Text = 'Polygon';
            app.TwolinesButton = uiradiobutton(app.SelectonetodrawButtonGroup);
            app.TwolinesButton.Text = 'Two lines';
            app.EllipseButton = uiradiobutton(app.SelectonetodrawButtonGroup);
            app.EllipseButton.Text = 'Ellipse';
            app.LineButton_2 = uiradiobutton(app.SelectonetodrawButtonGroup);
            app.LineButton_2.Visible = 'off';
            app.LineButton_2.Text = 'Line';
            app.LineButton_2.Value = true;
            app.DeleteButton_2 = uiradiobutton(app.SelectonetodrawButtonGroup);
            app.DeleteButton_2.Text = 'Delete';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.Visible = 'off';
            app.Label = uilabel(app.UIFigure);
            app.Label.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = modalitiesFinaal

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end