classdef modalities < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        TextArea                  matlab.ui.control.TextArea
        SelectplanetoincurrchangesButtonGroup  matlab.ui.container.ButtonGroup
        HorizontalEditField       matlab.ui.control.NumericEditField
        HorizontalEditFieldLabel  matlab.ui.control.Label
        ObliqueButton             matlab.ui.control.RadioButton
        CoronalButton             matlab.ui.control.RadioButton
        SagittalButton            matlab.ui.control.RadioButton
        AxialButton               matlab.ui.control.RadioButton
        ObliquePanel              matlab.ui.container.Panel
        UIAxes2_5                 matlab.ui.control.UIAxes
        CoronalPanel              matlab.ui.container.Panel
        UIAxes2_4                 matlab.ui.control.UIAxes
        SagittalPanel             matlab.ui.container.Panel
        UIAxes2_6                 matlab.ui.control.UIAxes
        BrowseButton              matlab.ui.control.Button
        AxialPanel_2              matlab.ui.container.Panel
        UIAxes2_2                 matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        AxHorValue
        AxVerValue
        horizontalLine % green
        verticalLine %red
        horizontalLineCor % green
        verticalLineCor
        horizontalLineSag % green
        verticalLineSag
        obliqueLine
        value
        CorHorValue
        CorVerValue
        SagHorValue
        SagVerValue
        img3d
        sizeof3d
        Sagittal
        Coronal
        Axial
        Oblique
        point
        normal
        pointx1
        pointy1
        pointx2
        pointy2
        pointz
        flag=false
        angle
       
    end


    methods (Access = private)
        
%         function updateData(app)
%         app.threshold = app.UIAxes2_2.CurrentPoint(3);
%         app.horizontalLine.Value = app.threshold;
%         drawnow;
%         end
       
        function updateAxHor(app)
        AxialSize=size(app.Axial);
        delete(app.horizontalLine);
        app.horizontalLine = yline(app.UIAxes2_2, app.AxHorValue,'g','LineWidth',2);
        drawnow;
        
        if((app.AxHorValue<0) | (app.AxHorValue>AxialSize(1)) )
            
           return;
        else
            app.Coronal = app.img3d(app.AxHorValue , :, :);
            imshow(imrotate(squeeze(app.Coronal),90),[],'Parent',app.UIAxes2_4),colormap gray;
        end
        app.horizontalLineCor = yline(app.UIAxes2_4, app.CorHorValue,'g','LineWidth',2);
        hold(app.UIAxes2_4, "off")
        app.verticalLineCor = xline(app.UIAxes2_4, app.CorVerValue,'r','LineWidth',2);
        hold(app.UIAxes2_4, "off")
        end
        
        function updateAxVer(app)
        delete(app.verticalLine);
        app.verticalLine = xline(app.UIAxes2_2, app.AxVerValue,'r','LineWidth',2);
        drawnow;
        app.Sagittal = app.img3d(:,app.AxVerValue , :);
        imshow(imrotate(squeeze(app.Sagittal),90),[],'Parent',app.UIAxes2_6),colormap gray;
        disp('vertical line axial')
        disp(app.AxVerValue)
        disp('end vertical line axial')
        app.horizontalLineSag = yline(app.UIAxes2_6, app.SagHorValue,'g','LineWidth',2);
        hold(app.UIAxes2_6, "off")
        app.verticalLineSag = xline(app.UIAxes2_6, app.SagVerValue,'r','LineWidth',2);
        hold(app.UIAxes2_6, "off")
        end
        
        function updateCorHor(app)
        delete(app.horizontalLineCor);
        app.horizontalLineCor = yline(app.UIAxes2_4, app.CorHorValue,'g','LineWidth',2);
        drawnow;
        app.Axial=app.img3d(:, :, app.CorHorValue);
        imshow(squeeze(app.Axial),[],'Parent',app.UIAxes2_2),colormap gray;
        app.horizontalLine = yline(app.UIAxes2_2, app.AxHorValue,'g','LineWidth',2);
        hold(app.UIAxes2_2, "off")
        app.verticalLine = xline(app.UIAxes2_2, app.AxVerValue,'r','LineWidth',2);
        hold(app.UIAxes2_2, "off")
        end
        
        
        function updateCorVer(app)
        delete(app.verticalLineCor);
        app.verticalLineCor = xline(app.UIAxes2_4, app.CorVerValue,'r','LineWidth',2);
        drawnow;
        app.Sagittal = app.img3d(:,app.CorVerValue , :);
        imshow(imrotate(squeeze(app.Sagittal),90),[],'Parent',app.UIAxes2_6),colormap gray;
        app.horizontalLineSag = yline(app.UIAxes2_6, app.SagHorValue,'g','LineWidth',2);
        hold(app.UIAxes2_6, "off")
        app.verticalLineSag = xline(app.UIAxes2_6, app.SagVerValue,'r','LineWidth',2);
        hold(app.UIAxes2_6, "off")
        end
        
        
        function updateSagHor(app)
        delete(app.horizontalLineSag);
        app.horizontalLineSag = yline(app.UIAxes2_6, app.SagHorValue,'g','LineWidth',2);
        drawnow;
        app.Axial=app.img3d(:, :, app.SagHorValue);
        imshow(squeeze(app.Axial),[],'Parent',app.UIAxes2_2),colormap gray;
        app.horizontalLine = yline(app.UIAxes2_2, app.AxHorValue,'g','LineWidth',2);
        hold(app.UIAxes2_2, "off")
        app.verticalLine = xline(app.UIAxes2_2, app.AxVerValue,'r','LineWidth',2);
        hold(app.UIAxes2_2, "off")
        end
        
        
        function updateSagVer(app)
        delete(app.verticalLineSag);
        app.verticalLineSag = xline(app.UIAxes2_6, app.SagVerValue,'r','LineWidth',2);
        drawnow;
        app.Coronal = app.img3d(app.SagVerValue , :, :);
        imshow(imrotate(squeeze(app.Coronal),90),[],'Parent',app.UIAxes2_4),colormap gray;
        app.horizontalLineCor = yline(app.UIAxes2_4, app.CorHorValue,'g','LineWidth',2);
        hold(app.UIAxes2_4, "off")
        app.verticalLineCor = xline(app.UIAxes2_4, app.CorVerValue,'r','LineWidth',2);
        hold(app.UIAxes2_4, "off")
        end
        
        
        function allevents(app,src,evt)
        evname = evt.EventName;
            switch(evname)
                case{'ROIMoved'}
                    %disp(['ROI moved previous position: ' mat2str(evt.PreviousPosition)]);
                    disp(['ROI moved current position: ' mat2str(evt.CurrentPosition())])
                    app.pointx1=app.obliqueLine.Position(1,1);
                    app.pointy1=app.obliqueLine.Position(1,2);
                    app.pointx2=app.obliqueLine.Position(2,1);
                    app.pointy2=app.obliqueLine.Position(2,2);
                    midpointX=(app.pointx1+app.pointx2)/2;
                    midpointY=(app.pointy1+app.pointy2)/2;
                    rise=app.pointy2-app.pointy1;
                    run=app.pointx2-app.pointx1;
                    app.angle = atand(rise/run);
                    disp(app.angle)
                    newrise=-run;
                    newrun=rise;
                    app.point=[midpointX midpointY 100];
                    app.normal = [newrun newrise 0];
                    
            end
        end

        function updateOblique(app,src,evt)
        if(app.flag==true)
        addlistener(app.obliqueLine,'ROIMoved',@app.allevents);
        end
            
        if(isscalar(app.angle) )
        app.Oblique = obliqueslice(app.img3d,app.point,app.normal,'OutputSize','Full');
        imshow(imrotate(app.Oblique,(app.angle-180)),[],'Parent',app.UIAxes2_5),colormap gray;
%         addlistener(app.obliqueLine,'ROIMoved',@app.updateOblique);
        end
        end
        
    end
    
    
    methods (Access = public)


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            %try
            fPath = uigetdir( 'C:\');
            files_wanted = dir(fullfile(fPath, '*.dcm'));
            
            %if user canceled browsing 
%             if ((fPath==0) && (files_wanted==0))
%                  return;
%             end
app.flag=true;
            Slices=length(files_wanted);
            for n=1:Slices
                FileName = fullfile(fPath, files_wanted(n).name);
                images{n}=dicomread(FileName); 
                img2d = images{n};
                app.img3d(:,:,n) = img2d;
            end
            app.sizeof3d=size(app.img3d);
            m=app.sizeof3d(1);
            app.Axial=app.img3d(:, :, (app.sizeof3d(3))./2);
            imshow(squeeze(app.Axial),[],'Parent',app.UIAxes2_2),colormap gray;
            
            %point = [20 30 15.5];
            %normal = [0 10 20];


            %enableDefaultInteractivity(app.UIAxes2_2); 
            disableDefaultInteractivity(app.UIAxes2_2)
            disableDefaultInteractivity(app.UIAxes2_4)
            disableDefaultInteractivity(app.UIAxes2_6)
            app.AxHorValue = 250;
            app.AxVerValue = 250;
            app.CorHorValue = 100;
            app.CorVerValue = 250;
            app.SagHorValue = 100;
            app.SagVerValue = 250;
            
            AxialSize=size(app.Axial);
            
            app.horizontalLine = yline(app.UIAxes2_2,app.AxHorValue,'g','LineWidth',2);
            
            app.obliqueLine = drawline(app.UIAxes2_2,'Position',[0 0;500 500]);

            app.point = [40 60 13];
            app.normal = [5 5 -20];
            app.Oblique = obliqueslice(app.img3d,app.point,app.normal);
           
            imshow(imrotate(app.Oblique,180),[],'Parent',app.UIAxes2_5),colormap gray;
             addlistener(app.obliqueLine,'ROIMoved',@app.updateOblique);


            hold(app.UIAxes2_2, "off")
            app.verticalLine = xline(app.UIAxes2_2, app.AxVerValue,'r','LineWidth',2);
            hold(app.UIAxes2_2, "off")
              
            
            app.Sagittal = app.img3d(:,(app.sizeof3d(1))./2 , :);
            
            
            app.Coronal = app.img3d((app.sizeof3d(2))./2 , :, :);
            
            imshow(imrotate(squeeze(app.Coronal),90),[],'Parent',app.UIAxes2_4),colormap gray;
            
            app.horizontalLineCor = yline(app.UIAxes2_4, app.CorHorValue,'g','LineWidth',2);
            hold(app.UIAxes2_4, "off")
            app.verticalLineCor = xline(app.UIAxes2_4, app.CorVerValue,'r','LineWidth',2);
            hold(app.UIAxes2_4, "off")
            imshow(imrotate(squeeze(app.Sagittal),90),[],'Parent',app.UIAxes2_6),colormap gray;
            app.horizontalLineSag = yline(app.UIAxes2_6, app.SagHorValue,'g','LineWidth',2);
            hold(app.UIAxes2_6, "off")
            app.verticalLineSag = xline(app.UIAxes2_6, app.SagVerValue,'r','LineWidth',2);
            hold(app.UIAxes2_6, "off")
            disp(size(app.Axial));%x & y 512 512
            disp(size(app.Coronal));%y % z 512 234
            disp(size(app.Sagittal));% x & z 512 234
            %catch ME
             %   warndlg('Please choose a dcm directory','Warning');
            %end
            %linkaxes([app.UIAxes2_2 app.UIAxes2_3 app.UIAxes2_4]);
        end

        % Callback function
        function UIFigureWindowKeyPress(app, event)
        
        if app.AxialButton.Value
           
            if strcmp(event.Key,'rightarrow')==1
                app.AxVerValue=app.AxVerValue+1;
                app.updateAxVer;
            elseif strcmp(event.Key, 'leftarrow')==1
                app.AxVerValue=app.AxVerValue-1;
                app.updateAxVer;
                
            elseif strcmp(event.Key, 'downarrow')==1
                app.AxHorValue=app.AxHorValue+1;
                app.updateAxHor;
                
            elseif strcmp(event.Key, 'uparrow')==1
                app.AxHorValue=app.AxHorValue-1;
                app.updateAxHor;
          
            end
        end
        if app.CoronalButton.Value
            if strcmp(event.Key,'rightarrow')==1
                app.CorVerValue=app.CorVerValue+1;
                app.updateCorVer;
            elseif strcmp(event.Key, 'leftarrow')==1
                app.CorVerValue=app.CorVerValue-1;
                app.updateCorVer;
                
            elseif strcmp(event.Key, 'downarrow')==1
                app.CorHorValue=app.CorHorValue+1;
                app.updateCorHor;
                
            elseif strcmp(event.Key, 'uparrow')==1
                app.CorHorValue=app.CorHorValue-1;
                app.updateCorHor;
            end
        end
        if app.SagittalButton.Value
            if strcmp(event.Key,'rightarrow')==1
                app.SagVerValue=app.SagVerValue+1;
                app.updateSagVer;
            elseif strcmp(event.Key, 'leftarrow')==1
                app.SagVerValue=app.SagVerValue-1;
                app.updateSagVer;
                
            elseif strcmp(event.Key, 'downarrow')==1
                app.SagHorValue=app.SagHorValue+1;
                app.updateSagHor;
                
            elseif strcmp(event.Key, 'uparrow')==1
                app.SagHorValue=app.SagHorValue-1;
                app.updateSagHor;
            end
            
        end

       
        end

        % Value changed function: HorizontalEditField
        function HorizontalEditFieldValueChanged(app, event)
            app.val = app.HorizontalEditField.Value;
            app.val=app.AxHorValue;
            app.AxHorValue=app.HorizontalEditField.Value;
        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
             if app.ObliqueButton.Value
             app.updateOblique;
             end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.AxialPanel_2 = uipanel(app.UIFigure);
            app.AxialPanel_2.Title = 'Axial';
            app.UIAxes2_2 = uiaxes(app.AxialPanel_2);
            title(app.UIAxes2_2, 'Title')
            xlabel(app.UIAxes2_2, 'X')
            ylabel(app.UIAxes2_2, 'Y')
            zlabel(app.UIAxes2_2, 'Z')
            app.UIAxes2_2.XLim = [0 512];
            app.UIAxes2_2.YLim = [0 512];
            app.BrowseButton = uibutton(app.UIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.SagittalPanel = uipanel(app.UIFigure);
            app.SagittalPanel.Title = 'Sagittal';
            app.UIAxes2_6 = uiaxes(app.SagittalPanel);
            title(app.UIAxes2_6, 'Title')
            xlabel(app.UIAxes2_6, 'X')
            ylabel(app.UIAxes2_6, 'Y')
            zlabel(app.UIAxes2_6, 'Z')
            app.UIAxes2_6.XLim = [0 512];
            app.UIAxes2_6.ZLim = [0 512];
            app.CoronalPanel = uipanel(app.UIFigure);
            app.CoronalPanel.Title = 'Coronal';
            app.UIAxes2_4 = uiaxes(app.CoronalPanel);
            title(app.UIAxes2_4, 'Title')
            xlabel(app.UIAxes2_4, 'X')
            ylabel(app.UIAxes2_4, 'Y')
            zlabel(app.UIAxes2_4, 'Z')
            app.UIAxes2_4.XLim = [0 512];
            app.UIAxes2_4.ZLim = [0 234];
            app.ObliquePanel = uipanel(app.UIFigure);
            app.ObliquePanel.Title = 'Oblique';
            app.UIAxes2_5 = uiaxes(app.ObliquePanel);
            title(app.UIAxes2_5, 'Title')
            xlabel(app.UIAxes2_5, 'X')
            ylabel(app.UIAxes2_5, 'Y')
            zlabel(app.UIAxes2_5, 'Z')
            app.SelectplanetoincurrchangesButtonGroup = uibuttongroup(app.UIFigure);
            app.SelectplanetoincurrchangesButtonGroup.Title = 'Select plane to incurr changes';
            app.AxialButton = uiradiobutton(app.SelectplanetoincurrchangesButtonGroup);
            app.AxialButton.Text = 'Axial';
            app.AxialButton.Value = true;
            app.SagittalButton = uiradiobutton(app.SelectplanetoincurrchangesButtonGroup);
            app.SagittalButton.Text = 'Sagittal';
            app.CoronalButton = uiradiobutton(app.SelectplanetoincurrchangesButtonGroup);
            app.CoronalButton.Text = 'Coronal';
            app.ObliqueButton = uiradiobutton(app.SelectplanetoincurrchangesButtonGroup);
            app.ObliqueButton.Text = 'Oblique';
            app.HorizontalEditFieldLabel = uilabel(app.SelectplanetoincurrchangesButtonGroup);
            app.HorizontalEditFieldLabel.Text = 'Horizontal';
            app.HorizontalEditField = uieditfield(app.SelectplanetoincurrchangesButtonGroup, 'numeric');
            app.HorizontalEditField.ValueChangedFcn = createCallbackFcn(app, @HorizontalEditFieldValueChanged, true);
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'Instructions:'; 'Left & right arrow to move red vertical line'; ''; ''; 'Up & down arrow to move green horizontal line'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = modalities

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end